classdef Flocking_App_modified < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        LeaderTrajectoryLabel           matlab.ui.control.Label
        XtEditFieldLabel                matlab.ui.control.Label
        XtEditField                     matlab.ui.control.EditField
        YtEditFieldLabel                matlab.ui.control.Label
        YtEditField                     matlab.ui.control.EditField
        AgentsEditFieldLabel            matlab.ui.control.Label
        AgentsEditField                 matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        RunButton                       matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        agentData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AgentsEditField
        function NumberOfAgentsUpdated(app, event)
            % This function adjusts the number of rows in the agent
            % position and velocity information so it refelcts the new
            % number of agents to be in the simulation.
            
            numNewAgents = app.AgentsEditField.Value;   % New total number of agents 
            numOldAgents = size(app.agentData,1);       % Old total number of agents
            
            % Updates the number of rows in the agent position and velocity
            % table to properly reflect the changes
            if numOldAgents < numNewAgents
                app.agentData = vertcat(app.agentData, zeros(numNewAgents-numOldAgents,4));
            else
                app.agentData = app.agentData(1:numNewAgents,:);
            end
            
            % Updates the Agent information data in the table
            app.UITable.Data = app.agentData;
            
        end

        % Button pushed function: RunButton
        function RunSimulation(app, event)
            % This function runs the simulation using the information given
            % by the user.
            
            app.CurrentIterationEditField.Value = 1;            % Initialize the iteration couter variable (current iteration field in app)
            numIterations = app.MaxIterationsEditField.Value;   % Number of iterations simulation is to run for
            
            app.agentData = app.UITable.Data;                   % Reads the agent position and velcoity data and stores it to the global variable
            agentPosition = app.agentData(:,1:2);               % stores the x,y position data for each agent
            agentVelocity = app.agentData(:,3:4);               % stores the x,y velcoity data for each agent
            
            % Variables to store the position and velocity data for each agent throughout the simulation
            agentPositionHistory = zeros(numIterations, 2*app.AgentsEditField.Value);
            agentVelocityHistory = zeros(numIterations, 2*app.AgentsEditField.Value);
            agentPositionHistory(1,:) = reshape(agentPosition',1,[]);
            agentVelocityHistory(1,:) = reshape(agentVelocity',1,[]);
            
            timeStep = app.TimeStepEditField.Value;             % "Time step" between each iteration of the simulation
            arenaDimensions = 5;                                % Setting the size of the square arena that the agents have to move in 

            syms t                                              % set up symbolic variable used when interpreting trajectories

            leaderXFunction = str2sym(app.XtEditField.Value);   % Reads in and converts input to a parametric equation for x component of leader trajectory
            leaderYFunction = str2sym(app.YtEditField.Value);   % Reads in and converts input to a parametric equation for y component of leader trajectory
            
            leaderXTrajectory(1,1) = agentPosition(1,1);        % Stores the x position of leader based on the user defined trajectory for each iteration of the simulation
            leaderYTrajectory(1,2) = agentPosition(1,2);        % Stores the y position of leader based on the user defined trajectory for each iteration of the simulation
            
            % Populate X and Y position of leader for each iteration based on defined trajectory
            if ~strcmpi(app.XtEditField.Value,'') && ~strcmpi(app.YtEditField.Value, '')
                for iteration = 2:maxIterations
                    t = (iteration-1)*timeStep;
                    leaderXTrajectory(iteration,1) = subs(leaderXFunction) + leaderXTrajectory(1,1);
                    leaderYTrajectory(iteration,2) = subs(leaderYFunction) + leaderYTrajectory(1,2);
                end
            end
            
            % TRIGGER SEQUENCE
            
            while app.CurrentIterationEditField.Value < numIterations
                A = CalculateA(app.KEditField, app.sigmaEditField, app.betaEditField,agentPosition);
                L = calculateL(A);
                
                app.CurrentIterationEditField.Value = app.CurrentIterationEditField.Value + 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeaderTrajectoryLabel = uilabel(app.UIFigure);
            app.LeaderTrajectoryLabel.Text = 'Leader Trajectory';
            app.XtEditFieldLabel = uilabel(app.UIFigure);
            app.XtEditFieldLabel.Text = 'X(t)';
            app.XtEditField = uieditfield(app.UIFigure, 'text');
            app.YtEditFieldLabel = uilabel(app.UIFigure);
            app.YtEditFieldLabel.Text = 'Y(t)';
            app.YtEditField = uieditfield(app.UIFigure, 'text');
            app.AgentsEditFieldLabel = uilabel(app.UIFigure);
            app.AgentsEditFieldLabel.Text = 'Agents';
            app.AgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfAgentsUpdated, true);
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X Position'; 'Y Position'; 'X Velocity'; 'Y Velocity'};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulation, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.CurrentIterationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flocking_App_modified

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end